classdef SignalQC_v001_Tabs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LOAD                        matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        LFPTab                      matlab.ui.container.Tab
        MinutesofRecEditFieldLabel  matlab.ui.control.Label
        recDur                      matlab.ui.control.NumericEditField
        SDofsignalLabel             matlab.ui.control.Label
        vLFP4                       matlab.ui.control.NumericEditField
        UniqueValuesLabel           matlab.ui.control.Label
        uLFP4                       matlab.ui.control.NumericEditField
        UniqueValuesLabel_2         matlab.ui.control.Label
        uLFP3                       matlab.ui.control.NumericEditField
        SDofsignalLabel_2           matlab.ui.control.Label
        vLFP3                       matlab.ui.control.NumericEditField
        UniqueValuesLabel_3         matlab.ui.control.Label
        uLFP2                       matlab.ui.control.NumericEditField
        SDofsignalLabel_3           matlab.ui.control.Label
        vLFP2                       matlab.ui.control.NumericEditField
        SDofsignalREF250Label       matlab.ui.control.Label
        vLFP1                       matlab.ui.control.NumericEditField
        UniqueValuesREF535Label     matlab.ui.control.Label
        uLFP1                       matlab.ui.control.NumericEditField
        LFPPanel                    matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        LFP1                        matlab.ui.control.UIAxes
        LFP2                        matlab.ui.control.UIAxes
        LFP3                        matlab.ui.control.UIAxes
        LFP4                        matlab.ui.control.UIAxes
        PSDPanel                    matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        lfpp1                       matlab.ui.control.UIAxes
        lfpp2                       matlab.ui.control.UIAxes
        lfpp3                       matlab.ui.control.UIAxes
        lfpp4                       matlab.ui.control.UIAxes
        Nyquist                     matlab.ui.control.StateButton
        CEEGIDPanel                 matlab.ui.container.Panel
        Lamp1                       matlab.ui.control.Lamp
        Lamp2                       matlab.ui.control.Lamp
        Lamp3                       matlab.ui.control.Lamp
        Lamp4                       matlab.ui.control.Lamp
        EEGTab                      matlab.ui.container.Tab
        EOGTab                      matlab.ui.container.Tab
        EMGTab                      matlab.ui.container.Tab
    end

    % REVISION HISTORY:
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % Revision 02/18/2021 - 11:57AM
    % 1. Remove guassian distribution
    % 2. Add label for LFP data


    properties (Access = private)
        fileLOC % Description
        fileLIST
        eeglist
        labTab
        curCHAN
        allChan
        lfpPLOTceeg
        allLFP
        Usefile
        allTTsf
    end
    
    methods (Access = private)
        
        function plotDATA(app)
            
            % START UPDATE PROGRESS BAR
            prog = uiprogressdlg(app.UIFigure,"Title","QC LFP",...
                "Indeterminate","on","Message","Processing LFP");
            drawnow
            
            % UPDATE to SHOW 
            prog.Message = "Computing common reference";
            drawnow
            % Extract LFP IDs
            LFPdat = zeros(4,length(app.allLFP.c1));
            for chI = 1:numel(fieldnames(app.allLFP))
                chIi = chI - 1;
                LFPdat(chI,:) = app.allLFP.(['c',num2str(chIi)]);
            end
            % Compute mean
            LFPmean = mean(LFPdat);
            
            % Sutract common reference
            LFPref = LFPdat - LFPmean;
            
            % UPDATE to SHOW
            prog.Message = "Computing values of interest";
            drawnow
            % Loop through LFP
            for li = 1:4
                
                % Should be between 5-50
                tmpUv = round((numel(unique(LFPref(li,:)))/2000)*100,1);
                app.(['uLFP',num2str(li)]).Value = tmpUv;
                
                % Should be between 5-100
                tmpVar = round(std(LFPref(li,:)));
                app.(['vLFP',num2str(li)]).Value = tmpVar;
                
            end
            
            % UPDATE to SHOW
            prog.Message = "Computing QC values";
            drawnow
            % Plot over Guassian
            for lp = 1:4
                
                lfpn = normalize(LFPref(lp,:),'scale');
                
                lfpnRs = randsample(lfpn, round(length(lfpn)*0.1), false);

                histogram(app.(['LFP',num2str(lp)]),lfpnRs,'EdgeColor','none',...
                    'FaceAlpha',0.5,'FaceColor','k');
                
                
                legend(app.(['LFP',num2str(lp)]),['Channel' , num2str(lp)])
                
            end
            
            % Plot PSD
            prog.Message = "Creating tables";
            drawnow
            TT1f = timetable(transpose(double(app.allLFP.c0)),'SampleRate',1375,'VariableName',"DO");
            TT2f = timetable(transpose(double(app.allLFP.c1)),'SampleRate',1375,'VariableName',"D1");
            TT3f = timetable(transpose(double(app.allLFP.c2)),'SampleRate',1375,'VariableName',"D2");
            TT4f = timetable(transpose(double(app.allLFP.c3)),'SampleRate',1375,'VariableName',"D3");

            app.allTTsf = {TT1f,TT2f,TT3f,TT4f};
            

            for tti = 1:4
                
                [pxx,f] = pspectrum(app.allTTsf{tti},"FrequencyLimits",[0 50]);
                plot(app.(['lfpp',num2str(tti)]),f,pow2db(pxx),'LineWidth',2)
                xlabel(app.(['lfpp',num2str(tti)]),'Frequency (Hz)')
                ylabel(app.(['lfpp',num2str(tti)]),'PowerSpec (dB)')

            end
            
            % END PROGRESS BAR
            close(prog)
            finalCHECK(app)
            
            app.Nyquist.Enable = 'on';
            
        end
        

        function finalCHECK(app)
            
            for li = 1:4
                
                if app.(['uLFP',num2str(li)]).Value > 1
                    uniqVal = 1;
                else 
                    uniqVal = 0;
                end
                
                if app.(['vLFP',num2str(li)]).Value > 1
                    varVal = 1;
                else
                    varVal = 0;
                end
                
                if app.recDur.Value > 2
                    durVal = 1;
                else
                    durVal = 0;
                end
                
                if uniqVal && varVal && durVal
                    app.(['Lamp',num2str(li)]).Color = 'g';
                end
                
            end
             
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LOAD
        function LOADMenuSelected(app, event)
            
            [app.Usefile,app.fileLOC] = uigetfile('*.mat');
          
            cd(app.fileLOC)
            
            % Combine first 3 files
            app.allChan = struct;
            curFile = app.Usefile;
            
            for ni = 1:4
                nii = ni - 1;
                
                load(curFile,['CDBS_',num2str(nii)]);
                dat1 = eval(['CDBS_',num2str(nii)]);
                app.allLFP.(['c',num2str(nii)]) = dat1;
                
            end
            % Extract recording information          
            load(curFile,['CDBS_',num2str(0),'_TimeBegin']);
            load(curFile,['CDBS_',num2str(0),'_TimeEnd']);
            tbegint = eval(['CDBS_',num2str(0),'_TimeBegin']);
            tendt = eval(['CDBS_',num2str(0),'_TimeEnd']);

            tmpTime = tendt - tbegint;

            app.recDur.Value = (round(tmpTime)/60);
            
            % Update plots
            plotDATA(app)
            
            
        end

        % Value changed function: Nyquist
        function NyquistValueChanged(app, event)
            value = app.Nyquist.Value;
            
            if value
                for tti = 1:4
                    [pxx,f] = pspectrum(app.allTTsf{tti},"FrequencyLimits",[0 round(1375/2)]);
                    plot(app.(['lfpp',num2str(tti)]),f,pow2db(pxx),'LineWidth',2)
                    xlabel(app.(['lfpp',num2str(tti)]),'Frequency (Hz)')
                    ylabel(app.(['lfpp',num2str(tti)]),'PowerSpec (dB)')

                    
                end
            else
                for tti = 1:4
                    [pxx,f] = pspectrum(app.allTTsf{tti},"FrequencyLimits",[0 50]);
                    plot(app.(['lfpp',num2str(tti)]),f,pow2db(pxx),'LineWidth',2)
                    xlabel(app.(['lfpp',num2str(tti)]),'Frequency (Hz)')
                    ylabel(app.(['lfpp',num2str(tti)]),'PowerSpec (dB)')

                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LOAD = uimenu(app.UIFigure);
            app.LOAD.MenuSelectedFcn = createCallbackFcn(app, @LOADMenuSelected, true);
            app.LOAD.Text = 'LOAD';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LFPTab = uitab(app.TabGroup);
            app.LFPTab.Title = 'LFP';
            app.MinutesofRecEditFieldLabel = uilabel(app.LFPTab);
            app.MinutesofRecEditFieldLabel.Text = 'Minutes of Rec. = ';
            app.recDur = uieditfield(app.LFPTab, 'numeric');
            app.recDur.Editable = 'off';
            app.SDofsignalLabel = uilabel(app.LFPTab);
            app.SDofsignalLabel.Text = 'SD of signal';
            app.vLFP4 = uieditfield(app.LFPTab, 'numeric');
            app.UniqueValuesLabel = uilabel(app.LFPTab);
            app.UniqueValuesLabel.Text = '% Unique Values';
            app.uLFP4 = uieditfield(app.LFPTab, 'numeric');
            app.UniqueValuesLabel_2 = uilabel(app.LFPTab);
            app.UniqueValuesLabel_2.Text = '% Unique Values';
            app.uLFP3 = uieditfield(app.LFPTab, 'numeric');
            app.SDofsignalLabel_2 = uilabel(app.LFPTab);
            app.SDofsignalLabel_2.Text = 'SD of signal';
            app.vLFP3 = uieditfield(app.LFPTab, 'numeric');
            app.UniqueValuesLabel_3 = uilabel(app.LFPTab);
            app.UniqueValuesLabel_3.Text = '% Unique Values';
            app.uLFP2 = uieditfield(app.LFPTab, 'numeric');
            app.SDofsignalLabel_3 = uilabel(app.LFPTab);
            app.SDofsignalLabel_3.Text = 'SD of signal';
            app.vLFP2 = uieditfield(app.LFPTab, 'numeric');
            app.SDofsignalREF250Label = uilabel(app.LFPTab);
            app.SDofsignalREF250Label.Text = {'SD of signal'; 'REF: 2-50'};
            app.vLFP1 = uieditfield(app.LFPTab, 'numeric');
            app.UniqueValuesREF535Label = uilabel(app.LFPTab);
            app.UniqueValuesREF535Label.Text = {'% Unique Values'; 'REF: 5-35'};
            app.uLFP1 = uieditfield(app.LFPTab, 'numeric');
            app.LFPPanel = uipanel(app.LFPTab);
            app.LFPPanel.Title = 'LFP';
            app.GridLayout = uigridlayout(app.LFPPanel);
            app.LFP1 = uiaxes(app.GridLayout);
            xlabel(app.LFP1, 'Scaled values')
            ylabel(app.LFP1, 'Count')
            zlabel(app.LFP1, 'Z')
            app.LFP2 = uiaxes(app.GridLayout);
            xlabel(app.LFP2, 'Scaled values')
            ylabel(app.LFP2, 'Count')
            zlabel(app.LFP2, 'Z')
            app.LFP3 = uiaxes(app.GridLayout);
            xlabel(app.LFP3, 'Scaled values')
            ylabel(app.LFP3, 'Count')
            zlabel(app.LFP3, 'Z')
            app.LFP4 = uiaxes(app.GridLayout);
            xlabel(app.LFP4, 'Scaled values')
            ylabel(app.LFP4, 'Count')
            zlabel(app.LFP4, 'Z')
            app.PSDPanel = uipanel(app.LFPTab);
            app.PSDPanel.Title = 'PSD';
            app.GridLayout2 = uigridlayout(app.PSDPanel);
            app.lfpp1 = uiaxes(app.GridLayout2);
            app.lfpp2 = uiaxes(app.GridLayout2);
            app.lfpp3 = uiaxes(app.GridLayout2);
            app.lfpp4 = uiaxes(app.GridLayout2);
            app.Nyquist = uibutton(app.LFPTab, 'state');
            app.Nyquist.ValueChangedFcn = createCallbackFcn(app, @NyquistValueChanged, true);
            app.Nyquist.Enable = 'off';
            app.Nyquist.Text = 'Nyquist';
            app.CEEGIDPanel = uipanel(app.LFPTab);
            app.CEEGIDPanel.Title = 'CEEG ID';
            app.Lamp1 = uilamp(app.CEEGIDPanel);
            app.Lamp2 = uilamp(app.CEEGIDPanel);
            app.Lamp3 = uilamp(app.CEEGIDPanel);
            app.Lamp4 = uilamp(app.CEEGIDPanel);
            app.EEGTab = uitab(app.TabGroup);
            app.EEGTab.Title = 'EEG';
            app.EOGTab = uitab(app.TabGroup);
            app.EOGTab.Title = 'EOG';
            app.EMGTab = uitab(app.TabGroup);
            app.EMGTab.Title = 'EMG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalQC_v001_Tabs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end